classdef algen_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ALGEN202122UIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Exerccio1Tab                    matlab.ui.container.Tab
        IniciarButton                   matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
        GeraesEditFieldLabel            matlab.ui.control.Label
        GeraesEditField                 matlab.ui.control.NumericEditField
        LowerBoundEditFieldLabel        matlab.ui.control.Label
        LowerBoundEditField             matlab.ui.control.NumericEditField
        UpperBoundEditFieldLabel        matlab.ui.control.Label
        UpperBoundEditField             matlab.ui.control.NumericEditField
        TamanhoPopulaoEditFieldLabel    matlab.ui.control.Label
        TamanhoPopulaoEditField         matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        PopulaoInicialKnobLabel         matlab.ui.control.Label
        PopulaoInicialKnob              matlab.ui.control.Knob
        LabelPiKnob                     matlab.ui.control.Label
        PiLabel                         matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        PiLabel_5                       matlab.ui.control.Label
        Exerccio2Tab                    matlab.ui.container.Tab
        IniciarButton_2                 matlab.ui.control.StateButton
        PopulaoInicialKnob_2Label       matlab.ui.control.Label
        PopulaoInicialKnob_2            matlab.ui.control.Knob
        GeraesEditField_2Label          matlab.ui.control.Label
        GeraesEditField_2               matlab.ui.control.NumericEditField
        PotnciaP1P3EditFieldLabel       matlab.ui.control.Label
        PotnciaP1P3EditField            matlab.ui.control.NumericEditField
        TamanhoPopulaoEditField_2Label  matlab.ui.control.Label
        TamanhoPopulaoEditField_2       matlab.ui.control.NumericEditField
        ResetButton_2                   matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        pg1label                        matlab.ui.control.Label
        pg2label                        matlab.ui.control.Label
        LabelPiKnob_2                   matlab.ui.control.Label
        PiLabel_2                       matlab.ui.control.Label
        p1_edit                         matlab.ui.control.NumericEditField
        p3_edit                         matlab.ui.control.NumericEditField
        puLabel                         matlab.ui.control.Label
        Exerccio3Tab                    matlab.ui.container.Tab
        IniciarButton_3                 matlab.ui.control.StateButton
        ResetButton_3                   matlab.ui.control.Button
        UITable2_2                      matlab.ui.control.Table
        Image_2                         matlab.ui.control.Image
        pg1label_2                      matlab.ui.control.Label
        pg2label_2                      matlab.ui.control.Label
        PopulaoInicialKnob_3Label       matlab.ui.control.Label
        PopulaoInicialKnob_3            matlab.ui.control.Knob
        GeraesEditField_3Label          matlab.ui.control.Label
        GeraesEditField_3               matlab.ui.control.NumericEditField
        PotnciaP1P3EditField_2Label     matlab.ui.control.Label
        PotnciaP1P3EditField_2          matlab.ui.control.NumericEditField
        TamanhoPopulaoEditField_3Label  matlab.ui.control.Label
        TamanhoPopulaoEditField_3       matlab.ui.control.NumericEditField
        LimiteProduoEditFieldLabel      matlab.ui.control.Label
        LimiteProduoEditField           matlab.ui.control.NumericEditField
        LimiteGeradoresButtonGroup      matlab.ui.container.ButtonGroup
        Gerador1Button                  matlab.ui.control.RadioButton
        Gerador2Button                  matlab.ui.control.RadioButton
        NenhumButton_2                  matlab.ui.control.RadioButton
        LabelPiKnob_3                   matlab.ui.control.Label
        PiLabel_3                       matlab.ui.control.Label
        p1_edit_2                       matlab.ui.control.NumericEditField
        p3_edit_2                       matlab.ui.control.NumericEditField
        puLabel_2                       matlab.ui.control.Label
        Exerccio4Tab                    matlab.ui.container.Tab
        IniciarButton_4                 matlab.ui.control.StateButton
        ResetButton_4                   matlab.ui.control.Button
        UITable2_3                      matlab.ui.control.Table
        Image_3                         matlab.ui.control.Image
        TamanhoPopulaoEditField_3Label_2  matlab.ui.control.Label
        TamanhoPopulaoEditField_4       matlab.ui.control.NumericEditField
        LimiteGeradoresButtonGroup_2    matlab.ui.container.ButtonGroup
        Gerador1Button_2                matlab.ui.control.RadioButton
        Gerador2Button_2                matlab.ui.control.RadioButton
        NenhumButton                    matlab.ui.control.RadioButton
        PopulaoInicialKnob_4Label       matlab.ui.control.Label
        PopulaoInicialKnob_4            matlab.ui.control.Knob
        GeraesEditField_4Label          matlab.ui.control.Label
        GeraesEditField_4               matlab.ui.control.NumericEditField
        PotnciaP1P3EditField_3Label     matlab.ui.control.Label
        PotnciaP1P3EditField_3          matlab.ui.control.NumericEditField
        LimiteProduoEditField_2Label    matlab.ui.control.Label
        LimiteProduoEditField_2         matlab.ui.control.NumericEditField
        jEditField_3Label               matlab.ui.control.Label
        jEditField_3                    matlab.ui.control.NumericEditField
        jEditField_3Label_2             matlab.ui.control.Label
        jEditField_4                    matlab.ui.control.NumericEditField
        jEditField_3Label_3             matlab.ui.control.Label
        jEditField_5                    matlab.ui.control.NumericEditField
        C1aPg1bPg12hLabel               matlab.ui.control.Label
        C2cPg2dPg22hLabel               matlab.ui.control.Label
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        cEditField                      matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        LabelPiKnob_4                   matlab.ui.control.Label
        PiLabel_4                       matlab.ui.control.Label
        p1_edit_3                       matlab.ui.control.NumericEditField
        p3_edit_3                       matlab.ui.control.NumericEditField
        puLabel_3                       matlab.ui.control.Label
    end


%     methods (Access = public)
%         
%        
%     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IniciarButton
        function IniciarButtonValueChanged(app, event)
             %value = app.IniciarButton.Value;
                
             %Após o clique no botão 'Iniciar', ocorre a desativação dos
             %parâmetros de edição 
             app.PopulaoInicialKnob.Enable = 'Off';
             app.TamanhoPopulaoEditField.Editable = 'Off';
             app.GeraesEditField.Editable = 'Off';
             app.LowerBoundEditField.Editable = 'Off';
             app.UpperBoundEditField.Editable = 'Off';
             app.ResetButton.Enable = 'Off';
                
            %Programa de optimizacao
            %X = GA(FITNESSFCN,NVARS,A,b,Aeq,beq,lb,ub,NONLCON,options)
                
            %Definição dos parâmetros da função ga - população inicial,
            %tamanho população e número de gerações
            options = gaoptimset('PlotFcns',...
                {@gaplotbestf,@gaplotbestindiv,@gaplotexpectation,@gaplotstopping},...
                'PopInitRange',[0;(app.PopulaoInicialKnob.Value)*pi],'PopulationSize',app.TamanhoPopulaoEditField.Value,'Generations',app.GeraesEditField.Value);
            [x_min,fval_min] = ga(@seno_min,1,[],[],[],[],app.LowerBoundEditField.Value,(app.UpperBoundEditField.Value)*pi,[],options);
            [x_max,fval_max] = ga(@seno_max,1,[],[],[],[],app.LowerBoundEditField.Value,(app.UpperBoundEditField.Value)*pi,[],options);
            
            
            y=linspace(app.LowerBoundEditField.Value,(app.UpperBoundEditField.Value)*pi,100);
            plot(app.UIAxes,y,sin(y),x_min,fval_min,'r*');
            
            hold(app.UIAxes,'on');
            plot(app.UIAxes,x_max,-fval_max,'b*');
            grid on
            hold(app.UIAxes,"off");
            
            app.UITable.RowName={'Minimo';'Máximo'};
            app.UITable.Data=[];
            app.UITable.Data{end+1,1}=x_min;
            app.UITable.Data{end,2}=fval_min;
            
            app.UITable.Data{end+1,1}=x_max;
            app.UITable.Data{end,2}=-fval_max;
            
            %Ativação dos parâmetros de edição 
            
            app.PopulaoInicialKnob.Enable = 'On';
            app.TamanhoPopulaoEditField.Editable = 'On';
            app.GeraesEditField.Editable = 'On';
            app.LowerBoundEditField.Editable = 'On';
            app.UpperBoundEditField.Editable = 'On';
            
            app.ResetButton.Enable = 'On';
        
        end

        % Value changed function: PopulaoInicialKnob
        function PopulaoInicialKnobValueChanged(app, event)
            %callback valor população inicial - label
            value = app.PopulaoInicialKnob.Value;
            app.LabelPiKnob.Text=num2str(value,2);
            drawnow
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            %Após o clique no botão 'Reset', ocorre a definição dos
            %parâmetros de edição para um valor default e os eixos do
            %gráfico são limpos
            
            cla(app.UIAxes);
            app.UITable.Data = {};
            app.PopulaoInicialKnob.Value = 2;
            app.TamanhoPopulaoEditField.Value = 30;
            app.GeraesEditField.Value = 30;
            app.UpperBoundEditField.Value = 2;
            app.LowerBoundEditField.Value = 0;
            
            app.PopulaoInicialKnob.Enable = 'On';
            app.TamanhoPopulaoEditField.Editable = 'On';
            app.GeraesEditField.Editable = 'On';
            app.LowerBoundEditField.Editable = 'On';
            app.UpperBoundEditField.Editable = 'On';


        end

        % Value changed function: IniciarButton_2
        function IniciarButton_2ValueChanged(app, event)
            %value = app.IniciarButton_2.Value;
            
            app.PopulaoInicialKnob_2.Enable = 'Off';
            app.TamanhoPopulaoEditField_2.Editable = 'Off';
            app.GeraesEditField_2.Editable = 'Off';
            app.ResetButton_2.Enable = 'Off';
            
            app.p1_edit.Editable = 'Off';
            app.p3_edit.Editable = 'Off';
            
            if (app.p1_edit.Value + app.p3_edit.Value) > 1
                
                opts = struct('WindowStyle','modal',... 
              'Interpreter','tex');
                errordlg('Valor Potência Total (P1+P3) tem de ser inferior a 1',...
             'Erro', opts);
         
            app.PopulaoInicialKnob_2.Enable = 'On';
            app.TamanhoPopulaoEditField_2.Editable = 'On';
            app.GeraesEditField_2.Editable = 'On';
            app.ResetButton_2.Enable = 'On';
            
            app.p1_edit.Editable = 'On';
            app.p3_edit.Editable = 'On';
         
            return
         
            end
            
            app.PotnciaP1P3EditField.Value = app.p1_edit.Value + app.p3_edit.Value;
            
            
            options = gaoptimset('PlotFcns',...
                {@gaplotbestf,@gaplotbestindiv,@gaplotexpectation,@gaplotstopping},...
                'PopInitRange',[0;app.PopulaoInicialKnob_2.Value],'PopulationSize',app.TamanhoPopulaoEditField_2.Value,'Generations',app.GeraesEditField_2.Value);
            %X = GA(FITNESSFCN,NVARS,A,b,Aeq,beq,lb,ub,NONLCON,options)
            [x_custo2,fval_custo2] = ga(@custo,2,[],[],[1 1],app.PotnciaP1P3EditField.Value,0,app.PotnciaP1P3EditField.Value,[],options);
           
%           Mostrar resultados na tabela
            
            app.UITable2.RowName={'Pg1';'Pg2';'Custo'};
            app.UITable2.Data=[];
            app.UITable2.Data{end+1,1}=x_custo2(1,1);
            
            app.UITable2.Data{end+1,1}=x_custo2(1,2);           
            app.UITable2.Data{end+1,1}=fval_custo2;
            
            app.pg1label.Text=num2str(x_custo2(1,1),3);
            app.pg2label.Text=num2str(x_custo2(1,2),3);
            drawnow
            
            app.PopulaoInicialKnob_2.Enable = 'On';
            app.TamanhoPopulaoEditField_2.Editable = 'On';
            app.GeraesEditField_2.Editable = 'On';
            app.ResetButton_2.Enable = 'On';
            
            app.p1_edit.Editable = 'On';
            app.p3_edit.Editable = 'On';
            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.UITable2.Data = {};
            app.PopulaoInicialKnob_2.Value = 2;
            app.TamanhoPopulaoEditField_2.Value = 30;
            app.GeraesEditField_2.Value = 30;
            app.PotnciaP1P3EditField.Value = 0.9;
            app.pg1label.Text = '';
            app.pg2label.Text = '';
            
            app.PopulaoInicialKnob_2.Enable = 'On';
            app.TamanhoPopulaoEditField_2.Editable = 'On';
            app.GeraesEditField_2.Editable = 'On';
            
            app.p1_edit.Editable = 'On';
            app.p3_edit.Editable = 'On';
            
            app.p1_edit.Value = 0.4;
            app.p3_edit.Value = 0.5;
        end

        % Value changed function: IniciarButton_3
        function IniciarButton_3ValueChanged(app, event)
            %value = app.IniciarButton_3.Value;
            
            app.PopulaoInicialKnob_3.Enable = 'Off';
            app.TamanhoPopulaoEditField_3.Editable = 'Off';
            app.GeraesEditField_3.Editable = 'Off';
            app.LimiteProduoEditField.Editable = 'Off';
            
            app.Gerador1Button.Enable = 'Off';
            app.Gerador2Button.Enable = 'Off';
            app.NenhumButton_2.Enable = 'Off';
            app.ResetButton_3.Enable = 'Off';
            
            app.p1_edit_2.Editable = 'Off';
            app.p3_edit_2.Editable = 'Off';
            
            if (app.p1_edit_2.Value + app.p3_edit_2.Value) > 1
                
                opts = struct('WindowStyle','modal',... 
              'Interpreter','tex');
                errordlg('Valor da Potência Total (P1+P3) tem de ser inferior a 1',...
             'Erro', opts);
         
            app.PopulaoInicialKnob_3.Enable = 'On';
            app.TamanhoPopulaoEditField_3.Editable = 'On';
            app.GeraesEditField_3.Editable = 'On';
            app.ResetButton_3.Enable = 'On';
            app.p1_edit_2.Editable = 'On';
            app.p3_edit_2.Editable = 'On';
            
            app.GeraesEditField_3.Editable = 'On';
            app.LimiteProduoEditField.Editable = 'On';
            
            app.Gerador1Button.Enable = 'On';
            app.Gerador2Button.Enable = 'On';
            app.NenhumButton_2.Enable = 'On';
         
            return
         
            end
            
            app.PotnciaP1P3EditField_2.Value = app.p1_edit_2.Value + app.p3_edit_2.Value;
            
            options = gaoptimset('PlotFcns',...
                {@gaplotbestf,@gaplotbestindiv,@gaplotexpectation,@gaplotstopping},...
                'PopInitRange',[0;app.PopulaoInicialKnob_3.Value],'PopulationSize',app.TamanhoPopulaoEditField_3.Value,'Generations',app.GeraesEditField_3.Value);
            
            A = [app.Gerador1Button.Value app.Gerador2Button.Value];
            
            %X = GA(FITNESSFCN,NVARS,A,b,Aeq,beq,lb,ub,NONLCON,options)
            [x_custo3,fval_custo3] = ga(@custo,2,double(A),app.LimiteProduoEditField.Value,[1 1],app.PotnciaP1P3EditField_2.Value,0,app.PotnciaP1P3EditField_2.Value,[],options);
           
            %Mostrar resultados na tabela
            
            app.UITable2_2.RowName={'Pg1';'Pg2';'Custo'};
            app.UITable2_2.Data=[];
            app.UITable2_2.Data{end+1,1}=x_custo3(1,1);
            
            app.UITable2_2.Data{end+1,1}=x_custo3(1,2);       
            app.UITable2_2.Data{end+1,1}=fval_custo3;
            
            app.pg1label_2.Text=num2str(x_custo3(1,1),3);
            app.pg2label_2.Text=num2str(x_custo3(1,2),3);
            drawnow
            
            
            app.PopulaoInicialKnob_3.Enable = 'On';
            app.TamanhoPopulaoEditField_3.Editable = 'On';
            app.GeraesEditField_3.Editable = 'On';
            app.LimiteProduoEditField.Editable = 'On';
            
            app.Gerador1Button.Enable = 'On';
            app.Gerador2Button.Enable = 'On';
            app.NenhumButton_2.Enable = 'On';
            app.ResetButton_3.Enable = 'On';
            
            app.p1_edit_2.Editable = 'On';
            app.p3_edit_2.Editable = 'On';
            
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.UITable2_2.Data = {};
            app.PopulaoInicialKnob_3.Value = 2;
            app.TamanhoPopulaoEditField_3.Value = 30;
            app.GeraesEditField_3.Value = 30;
            app.PotnciaP1P3EditField_2.Value = 0.9;
            app.LimiteProduoEditField.Value = 0.5;
            app.pg1label_2.Text = '';
            app.pg2label_2.Text = '';
            app.Gerador1Button.Value = true;
            app.Gerador2Button.Value = false;
            
            app.p1_edit_2.Value = 0.4;
            app.p3_edit_2.Value = 0.5;
            
            app.p1_edit_2.Editable = 'On';
            app.p3_edit_2.Editable = 'On';
            
            app.PopulaoInicialKnob_3.Enable = 'On';
            app.TamanhoPopulaoEditField_3.Editable = 'On';
            app.GeraesEditField_3.Editable = 'On';
            app.LimiteProduoEditField.Editable = 'On';
            
            app.Gerador1Button.Enable = 'On';
            app.Gerador2Button.Enable = 'On';
            app.NenhumButton_2.Enable = 'On';
        end

        % Value changed function: IniciarButton_4
        function IniciarButton_4ValueChanged(app, event)
            %value = app.IniciarButton_4.Value;
            
            
            app.PopulaoInicialKnob_4.Enable = 'Off';
            app.jEditField_5.Editable = 'Off';
            app.jEditField_4.Editable = 'Off';
            app.jEditField_3.Editable = 'Off';
            app.aEditField.Editable = 'Off';
            app.bEditField.Editable = 'Off';
            app.cEditField.Editable = 'Off';
            app.dEditField.Editable = 'Off';
            app.TamanhoPopulaoEditField_4.Editable = 'Off';
            app.GeraesEditField_4.Editable = 'Off';
            app.LimiteProduoEditField_2.Editable = 'Off';
            app.p1_edit_3.Editable = 'Off';
            app.p3_edit_3.Editable = 'Off';
            
            app.Gerador1Button_2.Enable = 'Off';
            app.Gerador2Button_2.Enable = 'Off';
            app.NenhumButton.Enable = 'Off';
            app.ResetButton_4.Enable = 'Off';
            
            if (app.p1_edit_3.Value + app.p3_edit_3.Value) > 1
                
                opts = struct('WindowStyle','modal',... 
              'Interpreter','tex');
                errordlg('Valor da Potência Total (P1+P3) tem de ser inferior a 1',...
             'Erro', opts);
         
            app.PopulaoInicialKnob_4.Enable = 'On';
            app.TamanhoPopulaoEditField_4.Editable = 'On';
            app.GeraesEditField_4.Editable = 'On';
            app.ResetButton_4.Enable = 'On';
            
            app.p1_edit_3.Editable = 'On';
            app.p3_edit_3.Editable = 'On';
            
            app.LimiteProduoEditField_2.Editable = 'On';
            app.Gerador1Button_2.Enable = 'On';
            app.Gerador2Button_2.Enable = 'On';
            app.NenhumButton.Enable = 'On';
            return
         
            end
            
            app.PotnciaP1P3EditField_3.Value = app.p1_edit_3.Value + app.p3_edit_3.Value;
            
            options = gaoptimset('PlotFcns',...
                {@gaplotbestf,@gaplotbestindiv,@gaplotexpectation,@gaplotstopping},...
                'PopInitRange',[0;app.PopulaoInicialKnob_4.Value],'PopulationSize',app.TamanhoPopulaoEditField_4.Value,'Generations',app.GeraesEditField_4.Value);
            
            A = [app.Gerador1Button_2.Value app.Gerador2Button_2.Value];            
            
            %Funcao a optimizar
            function nodes=custo3(x)
            %Funcao fitness
            valor_Aeq1 = (1/app.jEditField_5.Value)+(1/app.jEditField_3.Value);
            valor_Aeq2 = (1/app.jEditField_3.Value);
            
            %pg2=13.33*x(1)-10*x(2);             
            pg2=valor_Aeq1*x(1)-valor_Aeq2*x(2); 
            pg1=(app.PotnciaP1P3EditField_3.Value)-pg2;
            
            c1=(app.aEditField.Value)*pg1+(app.bEditField.Value)*pg1^2;
            c2=(app.cEditField.Value)*pg2+(app.dEditField.Value)*pg2^2;
            nodes=c1+c2;
            end

            %Programa de optimizacao
            %X = GA(FITNESSFCN,NVARS,A,b,Aeq,beq,lb,ub,NONLCON,options)  
            
            Aeq1=(1/(app.jEditField_3.Value));
            Aeq2=(1/(app.jEditField_4.Value))+(1/(app.jEditField_3.Value));
            
            [x_custo4,fval_custo4] = ga(@custo3,2,double(A),app.LimiteProduoEditField_2.Value,[Aeq1 -Aeq2],app.PotnciaP1P3EditField_3.Value,[],[],[],options);
           
            %Mostrar resultados na tabela
            
            app.UITable2_3.RowName={'θ1';'θ2';'Custo';'P12';'P13';'P23';'Produção'};
            app.UITable2_3.Data=[];
            
            app.UITable2_3.Data{end+1,1}=x_custo4(1,1);
            app.UITable2_3.Data{end+1,1}=x_custo4(1,2);
            app.UITable2_3.Data{end+1,1}=fval_custo4;
            
            app.UITable2_3.Data{end+1,1}=-x_custo4(1)/(app.jEditField_5.Value);
            app.UITable2_3.Data{end+1,1}=-x_custo4(2)/(app.jEditField_4.Value);
            app.UITable2_3.Data{end+1,1}=(x_custo4(1)-x_custo4(2))/(app.jEditField_3.Value);
            app.UITable2_3.Data{end+1,1}=(-x_custo4(2)/(app.jEditField_4.Value))+((x_custo4(1)-x_custo4(2))/(app.jEditField_3.Value));
            

            app.PopulaoInicialKnob_4.Enable = 'On';
            app.jEditField_5.Editable = 'On';
            app.jEditField_4.Editable = 'On';
            app.jEditField_3.Editable = 'On';
            app.aEditField.Editable = 'On';
            app.bEditField.Editable = 'On';
            app.cEditField.Editable = 'On';
            app.dEditField.Editable = 'On';
            app.TamanhoPopulaoEditField_4.Editable = 'On';
            app.GeraesEditField_4.Editable = 'On';
            app.LimiteProduoEditField_2.Editable = 'On';
            
            app.p1_edit_3.Editable = 'On';
            app.p3_edit_3.Editable = 'On';
            
            app.Gerador1Button_2.Enable = 'On';
            app.Gerador2Button_2.Enable = 'On';
            app.NenhumButton.Enable = 'On';
            app.ResetButton_4.Enable = 'On';
            
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            app.UITable2_3.Data = {};
            app.PopulaoInicialKnob_4.Value = 2;
            app.TamanhoPopulaoEditField_4.Value = 30;
            app.GeraesEditField_4.Value = 30;
            app.PotnciaP1P3EditField_3.Value = 0.9;
            app.LimiteProduoEditField_2.Value = 0.5;
            app.Gerador1Button_2.Value = true;
            app.Gerador2Button_2.Value = false;
            app.jEditField_5.Value = 0.3;
            app.jEditField_4.Value = 0.1;
            app.jEditField_3.Value = 0.1;
            app.aEditField.Value = 0.3;
            app.bEditField.Value = 0.01;
            app.cEditField.Value = 0.2;
            app.dEditField.Value = 0.3;
            
            app.p1_edit_3.Value = 0.4;
            app.p3_edit_3.Value = 0.5;
            
            app.PopulaoInicialKnob_4.Enable = 'On';    
            app.jEditField_5.Editable = 'On';
            app.jEditField_4.Editable = 'On';
            app.jEditField_3.Editable = 'On';
            app.aEditField.Editable = 'On';
            app.bEditField.Editable = 'On';
            app.cEditField.Editable = 'On';
            app.dEditField.Editable = 'On';
            app.TamanhoPopulaoEditField_4.Editable = 'On';
            app.GeraesEditField_4.Editable = 'On';
            app.LimiteProduoEditField_2.Editable = 'On';
            app.p1_edit_3.Editable = 'On';
            app.p3_edit_3.Editable = 'On';
            
            app.Gerador1Button_2.Enable = 'On';
            app.Gerador2Button_2.Enable = 'On';
            app.NenhumButton.Enable = 'On';
        end

        % Value changed function: PopulaoInicialKnob_2
        function PopulaoInicialKnob_2ValueChanged(app, event)
            value = app.PopulaoInicialKnob_2.Value;
            
            app.LabelPiKnob_2.Text=num2str(value,2);
            drawnow
            
        end

        % Value changed function: PopulaoInicialKnob_3
        function PopulaoInicialKnob_3ValueChanged(app, event)
            value = app.PopulaoInicialKnob_3.Value;
            
            app.LabelPiKnob_3.Text=num2str(value,2);
            drawnow
        end

        % Value changed function: PopulaoInicialKnob_4
        function PopulaoInicialKnob_4ValueChanged(app, event)
            value = app.PopulaoInicialKnob_4.Value;
            
            app.LabelPiKnob_4.Text=num2str(value,2);
            drawnow
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ALGEN202122UIFigure = uifigure('Visible', 'off');
            app.ALGEN202122UIFigure.Name = 'ALGEN 2021/22';
            app.TabGroup = uitabgroup(app.ALGEN202122UIFigure);
            app.Exerccio1Tab = uitab(app.TabGroup);
            app.Exerccio1Tab.Title = 'Exercício 1';
            app.IniciarButton = uibutton(app.Exerccio1Tab, 'state');
            app.IniciarButton.ValueChangedFcn = createCallbackFcn(app, @IniciarButtonValueChanged, true);
            app.IniciarButton.Text = 'Iniciar';
            app.UIAxes = uiaxes(app.Exerccio1Tab);
            title(app.UIAxes, 'Função Seno')
            xlabel(app.UIAxes, 'Período (rad)')
            ylabel(app.UIAxes, 'Amplitude')
            app.GeraesEditFieldLabel = uilabel(app.Exerccio1Tab);
            app.GeraesEditFieldLabel.Text = 'Gerações';
            app.GeraesEditField = uieditfield(app.Exerccio1Tab, 'numeric');
            app.GeraesEditField.Value = 30;
            app.LowerBoundEditFieldLabel = uilabel(app.Exerccio1Tab);
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
            app.LowerBoundEditField = uieditfield(app.Exerccio1Tab, 'numeric');
            app.LowerBoundEditField.Limits = [0 Inf];
            app.UpperBoundEditFieldLabel = uilabel(app.Exerccio1Tab);
            app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
            app.UpperBoundEditField = uieditfield(app.Exerccio1Tab, 'numeric');
            app.UpperBoundEditField.Value = 2;
            app.TamanhoPopulaoEditFieldLabel = uilabel(app.Exerccio1Tab);
            app.TamanhoPopulaoEditFieldLabel.Text = 'Tamanho População';
            app.TamanhoPopulaoEditField = uieditfield(app.Exerccio1Tab, 'numeric');
            app.TamanhoPopulaoEditField.Value = 30;
            app.UITable = uitable(app.Exerccio1Tab);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {''};
            app.PopulaoInicialKnobLabel = uilabel(app.Exerccio1Tab);
            app.PopulaoInicialKnobLabel.Text = 'População Inicial';
            app.PopulaoInicialKnob = uiknob(app.Exerccio1Tab, 'continuous');
            app.PopulaoInicialKnob.Limits = [0 10];
            app.PopulaoInicialKnob.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PopulaoInicialKnob.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.PopulaoInicialKnob.ValueChangedFcn = createCallbackFcn(app, @PopulaoInicialKnobValueChanged, true);
            app.PopulaoInicialKnob.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PopulaoInicialKnob.Value = 2;
            app.LabelPiKnob = uilabel(app.Exerccio1Tab);
            app.LabelPiKnob.Text = '2';
            app.PiLabel = uilabel(app.Exerccio1Tab);
            app.PiLabel.Text = 'Pi';
            app.ResetButton = uibutton(app.Exerccio1Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PiLabel_5 = uilabel(app.Exerccio1Tab);
            app.PiLabel_5.Text = '  Pi';
            app.Exerccio2Tab = uitab(app.TabGroup);
            app.Exerccio2Tab.Title = 'Exercício 2';
            app.IniciarButton_2 = uibutton(app.Exerccio2Tab, 'state');
            app.IniciarButton_2.ValueChangedFcn = createCallbackFcn(app, @IniciarButton_2ValueChanged, true);
            app.IniciarButton_2.Text = 'Iniciar';
            app.PopulaoInicialKnob_2Label = uilabel(app.Exerccio2Tab);
            app.PopulaoInicialKnob_2Label.Text = 'População Inicial';
            app.PopulaoInicialKnob_2 = uiknob(app.Exerccio2Tab, 'continuous');
            app.PopulaoInicialKnob_2.Limits = [0 10];
            app.PopulaoInicialKnob_2.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PopulaoInicialKnob_2.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.PopulaoInicialKnob_2.ValueChangedFcn = createCallbackFcn(app, @PopulaoInicialKnob_2ValueChanged, true);
            app.PopulaoInicialKnob_2.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PopulaoInicialKnob_2.Value = 2;
            app.GeraesEditField_2Label = uilabel(app.Exerccio2Tab);
            app.GeraesEditField_2Label.Text = 'Gerações';
            app.GeraesEditField_2 = uieditfield(app.Exerccio2Tab, 'numeric');
            app.GeraesEditField_2.Value = 30;
            app.PotnciaP1P3EditFieldLabel = uilabel(app.Exerccio2Tab);
            app.PotnciaP1P3EditFieldLabel.Text = 'Potência (P1 + P3)';
            app.PotnciaP1P3EditField = uieditfield(app.Exerccio2Tab, 'numeric');
            app.PotnciaP1P3EditField.Limits = [0 Inf];
            app.PotnciaP1P3EditField.Editable = 'off';
            app.PotnciaP1P3EditField.Value = 0.9;
            app.TamanhoPopulaoEditField_2Label = uilabel(app.Exerccio2Tab);
            app.TamanhoPopulaoEditField_2Label.Text = 'Tamanho População';
            app.TamanhoPopulaoEditField_2 = uieditfield(app.Exerccio2Tab, 'numeric');
            app.TamanhoPopulaoEditField_2.Value = 30;
            app.ResetButton_2 = uibutton(app.Exerccio2Tab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.UITable2 = uitable(app.Exerccio2Tab);
            app.UITable2.ColumnName = {'Resultados'};
            app.UITable2.RowName = {''};
            app.Image = uiimage(app.Exerccio2Tab);
            app.Image.ImageSource = 'geradores.png';
            app.pg1label = uilabel(app.Exerccio2Tab);
            app.pg1label.Text = '';
            app.pg2label = uilabel(app.Exerccio2Tab);
            app.pg2label.Text = '';
            app.LabelPiKnob_2 = uilabel(app.Exerccio2Tab);
            app.LabelPiKnob_2.Text = '2';
            app.PiLabel_2 = uilabel(app.Exerccio2Tab);
            app.PiLabel_2.Text = 'Pi';
            app.p1_edit = uieditfield(app.Exerccio2Tab, 'numeric');
            app.p1_edit.Limits = [0 1];
            app.p1_edit.Value = 0.4;
            app.p3_edit = uieditfield(app.Exerccio2Tab, 'numeric');
            app.p3_edit.Limits = [0 1];
            app.p3_edit.Value = 0.5;
            app.puLabel = uilabel(app.Exerccio2Tab);
            app.puLabel.Text = '  p.u.';
            app.Exerccio3Tab = uitab(app.TabGroup);
            app.Exerccio3Tab.Title = 'Exercício 3';
            app.IniciarButton_3 = uibutton(app.Exerccio3Tab, 'state');
            app.IniciarButton_3.ValueChangedFcn = createCallbackFcn(app, @IniciarButton_3ValueChanged, true);
            app.IniciarButton_3.Text = 'Iniciar';
            app.ResetButton_3 = uibutton(app.Exerccio3Tab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.UITable2_2 = uitable(app.Exerccio3Tab);
            app.UITable2_2.ColumnName = {'Resultados'};
            app.UITable2_2.RowName = {''};
            app.Image_2 = uiimage(app.Exerccio3Tab);
            app.Image_2.ImageSource = 'geradores.png';
            app.pg1label_2 = uilabel(app.Exerccio3Tab);
            app.pg1label_2.Text = '';
            app.pg2label_2 = uilabel(app.Exerccio3Tab);
            app.pg2label_2.Text = '';
            app.PopulaoInicialKnob_3Label = uilabel(app.Exerccio3Tab);
            app.PopulaoInicialKnob_3Label.Text = 'População Inicial';
            app.PopulaoInicialKnob_3 = uiknob(app.Exerccio3Tab, 'continuous');
            app.PopulaoInicialKnob_3.Limits = [0 10];
            app.PopulaoInicialKnob_3.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PopulaoInicialKnob_3.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.PopulaoInicialKnob_3.ValueChangedFcn = createCallbackFcn(app, @PopulaoInicialKnob_3ValueChanged, true);
            app.PopulaoInicialKnob_3.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PopulaoInicialKnob_3.Value = 2;
            app.GeraesEditField_3Label = uilabel(app.Exerccio3Tab);
            app.GeraesEditField_3Label.Text = 'Gerações';
            app.GeraesEditField_3 = uieditfield(app.Exerccio3Tab, 'numeric');
            app.GeraesEditField_3.Value = 30;
            app.PotnciaP1P3EditField_2Label = uilabel(app.Exerccio3Tab);
            app.PotnciaP1P3EditField_2Label.Text = 'Potência (P1 + P3)';
            app.PotnciaP1P3EditField_2 = uieditfield(app.Exerccio3Tab, 'numeric');
            app.PotnciaP1P3EditField_2.Limits = [0 1];
            app.PotnciaP1P3EditField_2.Editable = 'off';
            app.PotnciaP1P3EditField_2.Value = 0.9;
            app.TamanhoPopulaoEditField_3Label = uilabel(app.Exerccio3Tab);
            app.TamanhoPopulaoEditField_3Label.Text = 'Tamanho População';
            app.TamanhoPopulaoEditField_3 = uieditfield(app.Exerccio3Tab, 'numeric');
            app.TamanhoPopulaoEditField_3.Value = 30;
            app.LimiteProduoEditFieldLabel = uilabel(app.Exerccio3Tab);
            app.LimiteProduoEditFieldLabel.Text = 'Limite Produção';
            app.LimiteProduoEditField = uieditfield(app.Exerccio3Tab, 'numeric');
            app.LimiteProduoEditField.Limits = [0 1];
            app.LimiteProduoEditField.Value = 0.5;
            app.LimiteGeradoresButtonGroup = uibuttongroup(app.Exerccio3Tab);
            app.LimiteGeradoresButtonGroup.Title = 'Limite Geradores';
            app.Gerador1Button = uiradiobutton(app.LimiteGeradoresButtonGroup);
            app.Gerador1Button.Text = 'Gerador 1';
            app.Gerador1Button.Value = true;
            app.Gerador2Button = uiradiobutton(app.LimiteGeradoresButtonGroup);
            app.Gerador2Button.Text = 'Gerador 2';
            app.NenhumButton_2 = uiradiobutton(app.LimiteGeradoresButtonGroup);
            app.NenhumButton_2.Text = 'Nenhum';
            app.LabelPiKnob_3 = uilabel(app.Exerccio3Tab);
            app.LabelPiKnob_3.Text = '2';
            app.PiLabel_3 = uilabel(app.Exerccio3Tab);
            app.PiLabel_3.Text = 'Pi';
            app.p1_edit_2 = uieditfield(app.Exerccio3Tab, 'numeric');
            app.p1_edit_2.Limits = [0 1];
            app.p1_edit_2.Value = 0.4;
            app.p3_edit_2 = uieditfield(app.Exerccio3Tab, 'numeric');
            app.p3_edit_2.Limits = [0 1];
            app.p3_edit_2.Value = 0.5;
            app.puLabel_2 = uilabel(app.Exerccio3Tab);
            app.puLabel_2.Text = '  p.u.';
            app.Exerccio4Tab = uitab(app.TabGroup);
            app.Exerccio4Tab.Title = 'Exercício 4';
            app.IniciarButton_4 = uibutton(app.Exerccio4Tab, 'state');
            app.IniciarButton_4.ValueChangedFcn = createCallbackFcn(app, @IniciarButton_4ValueChanged, true);
            app.IniciarButton_4.Text = 'Iniciar';
            app.ResetButton_4 = uibutton(app.Exerccio4Tab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.UITable2_3 = uitable(app.Exerccio4Tab);
            app.UITable2_3.ColumnName = {'Resultados'};
            app.UITable2_3.RowName = {''};
            app.Image_3 = uiimage(app.Exerccio4Tab);
            app.Image_3.ImageSource = 'geradores2.png';
            app.TamanhoPopulaoEditField_3Label_2 = uilabel(app.Exerccio4Tab);
            app.TamanhoPopulaoEditField_3Label_2.Text = 'Tamanho População';
            app.TamanhoPopulaoEditField_4 = uieditfield(app.Exerccio4Tab, 'numeric');
            app.TamanhoPopulaoEditField_4.Value = 30;
            app.LimiteGeradoresButtonGroup_2 = uibuttongroup(app.Exerccio4Tab);
            app.LimiteGeradoresButtonGroup_2.Title = 'Limite Geradores';
            app.Gerador1Button_2 = uiradiobutton(app.LimiteGeradoresButtonGroup_2);
            app.Gerador1Button_2.Text = 'Gerador 1';
            app.Gerador1Button_2.Value = true;
            app.Gerador2Button_2 = uiradiobutton(app.LimiteGeradoresButtonGroup_2);
            app.Gerador2Button_2.Text = 'Gerador 2';
            app.NenhumButton = uiradiobutton(app.LimiteGeradoresButtonGroup_2);
            app.NenhumButton.Text = 'Nenhum';
            app.PopulaoInicialKnob_4Label = uilabel(app.Exerccio4Tab);
            app.PopulaoInicialKnob_4Label.Text = 'População Inicial';
            app.PopulaoInicialKnob_4 = uiknob(app.Exerccio4Tab, 'continuous');
            app.PopulaoInicialKnob_4.Limits = [0 10];
            app.PopulaoInicialKnob_4.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PopulaoInicialKnob_4.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.PopulaoInicialKnob_4.ValueChangedFcn = createCallbackFcn(app, @PopulaoInicialKnob_4ValueChanged, true);
            app.PopulaoInicialKnob_4.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PopulaoInicialKnob_4.Value = 2;
            app.GeraesEditField_4Label = uilabel(app.Exerccio4Tab);
            app.GeraesEditField_4Label.Text = 'Gerações';
            app.GeraesEditField_4 = uieditfield(app.Exerccio4Tab, 'numeric');
            app.GeraesEditField_4.Value = 30;
            app.PotnciaP1P3EditField_3Label = uilabel(app.Exerccio4Tab);
            app.PotnciaP1P3EditField_3Label.Text = 'Potência (P1 + P3)';
            app.PotnciaP1P3EditField_3 = uieditfield(app.Exerccio4Tab, 'numeric');
            app.PotnciaP1P3EditField_3.Limits = [0 1];
            app.PotnciaP1P3EditField_3.Editable = 'off';
            app.PotnciaP1P3EditField_3.Value = 0.9;
            app.LimiteProduoEditField_2Label = uilabel(app.Exerccio4Tab);
            app.LimiteProduoEditField_2Label.Text = 'Limite Produção';
            app.LimiteProduoEditField_2 = uieditfield(app.Exerccio4Tab, 'numeric');
            app.LimiteProduoEditField_2.Limits = [0 1];
            app.LimiteProduoEditField_2.Value = 0.5;
            app.jEditField_3Label = uilabel(app.Exerccio4Tab);
            app.jEditField_3Label.Text = 'j';
            app.jEditField_3 = uieditfield(app.Exerccio4Tab, 'numeric');
            app.jEditField_3.Value = 0.1;
            app.jEditField_3Label_2 = uilabel(app.Exerccio4Tab);
            app.jEditField_3Label_2.Text = 'j';
            app.jEditField_4 = uieditfield(app.Exerccio4Tab, 'numeric');
            app.jEditField_4.Value = 0.1;
            app.jEditField_3Label_3 = uilabel(app.Exerccio4Tab);
            app.jEditField_3Label_3.Text = 'j';
            app.jEditField_5 = uieditfield(app.Exerccio4Tab, 'numeric');
            app.jEditField_5.Value = 0.3;
            app.C1aPg1bPg12hLabel = uilabel(app.Exerccio4Tab);
            app.C1aPg1bPg12hLabel.Text = 'C1 = a Pg1 + b Pg1^2 (€/h) ';
            app.C2cPg2dPg22hLabel = uilabel(app.Exerccio4Tab);
            app.C2cPg2dPg22hLabel.Text = 'C2 = c Pg2 + d Pg2^2 (€/h) ';
            app.aEditFieldLabel = uilabel(app.Exerccio4Tab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Exerccio4Tab, 'numeric');
            app.aEditField.Value = 0.3;
            app.bEditFieldLabel = uilabel(app.Exerccio4Tab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Exerccio4Tab, 'numeric');
            app.bEditField.Value = 0.01;
            app.cEditFieldLabel = uilabel(app.Exerccio4Tab);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Exerccio4Tab, 'numeric');
            app.cEditField.Value = 0.2;
            app.dEditFieldLabel = uilabel(app.Exerccio4Tab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Exerccio4Tab, 'numeric');
            app.dEditField.Value = 0.3;
            app.Label = uilabel(app.Exerccio4Tab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Exerccio4Tab);
            app.Label_2.Text = '';
            app.LabelPiKnob_4 = uilabel(app.Exerccio4Tab);
            app.LabelPiKnob_4.Text = '2';
            app.PiLabel_4 = uilabel(app.Exerccio4Tab);
            app.PiLabel_4.Text = 'Pi';
            app.p1_edit_3 = uieditfield(app.Exerccio4Tab, 'numeric');
            app.p1_edit_3.Limits = [0 1];
            app.p1_edit_3.Value = 0.4;
            app.p3_edit_3 = uieditfield(app.Exerccio4Tab, 'numeric');
            app.p3_edit_3.Limits = [0 1];
            app.p3_edit_3.Value = 0.5;
            app.puLabel_3 = uilabel(app.Exerccio4Tab);
            app.puLabel_3.Text = '  p.u.';
            % Show the figure after all components are created
            app.ALGEN202122UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = algen_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ALGEN202122UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ALGEN202122UIFigure)
        end
    end
end